classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        browseButton               matlab.ui.control.Button
        fileaddressEditField       matlab.ui.control.EditField
        fileaddressEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        filename;
        pathname;
        orginalaudiodata;
        fs;
        audio;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
            [file, Pathname]=uigetfile('*.wav','wav file selector');
            app.filename=file;
            app.pathname=strcat(file,Pathname);
            [app.orginalaudiodata,app.fs]=audioread(file);
            app.audio=app.orginalaudiodata;
            app.fileaddressEditField.Value=app.filename;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.fileaddressEditFieldLabel = uilabel(app.Panel);
            app.fileaddressEditFieldLabel.Text = 'file address';
            app.fileaddressEditField = uieditfield(app.Panel, 'text');
            app.fileaddressEditField.Placeholder = 'browse file';
            app.browseButton = uibutton(app.Panel, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end